classdef appDesigner_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        WhatdateisittodayDatePickerLabel  matlab.ui.control.Label
        WhatdateisittodayDatePicker  matlab.ui.control.DatePicker
        WavelengthSliderLabel        matlab.ui.control.Label
        WavelengthSlider             matlab.ui.control.Slider
        AmplitudeSliderLabel         matlab.ui.control.Label
        AmplitudeSlider              matlab.ui.control.Slider
        ShiftSliderLabel             matlab.ui.control.Label
        ShiftSlider                  matlab.ui.control.Slider
    end

    
    properties (Access = private)
        lineColor = 'k';
        x = linspace(-2*pi, 2*pi, 100);
    end
    
    methods (Access = private)
        function drawGraph(app)
            set(app.UIAxes.Children, 'YData', app.AmplitudeSlider.Value.*sin(2*pi./app.WavelengthSlider.Value.*app.x ...
                - app.ShiftSlider.Value));
            set(app.UIAxes.Children, 'Color',  app.lineColor)
            if app.lineColor == 'r'
                title(app.UIAxes, 'Plot wave 🎄')
            else
                title(app.UIAxes, 'Plot wave')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plot(app.UIAxes, app.x, zeros(size(app.x)), app.lineColor);
        end

        % Value changed function: ShiftSlider
        function ShiftSliderValueChanged(app, event)
            drawGraph(app);
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            drawGraph(app);
        end

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
             drawGraph(app);
        end

        % Value changed function: WhatdateisittodayDatePicker
        function WhatdateisittodayDatePickerValueChanged(app, event)
            currentDate = char(app.WhatdateisittodayDatePicker.Value);
            if strcmp(currentDate(end-4:end), '12-24')
                app.lineColor = 'r';
                drawGraph(app);
            else
                app.lineColor = 'k';
                drawGraph(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot a wave')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.WhatdateisittodayDatePickerLabel = uilabel(app.UIFigure);
            app.WhatdateisittodayDatePickerLabel.Text = 'What date is it today?';
            app.WhatdateisittodayDatePicker = uidatepicker(app.UIFigure);
            app.WhatdateisittodayDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.WhatdateisittodayDatePicker.ValueChangedFcn = createCallbackFcn(app, @WhatdateisittodayDatePickerValueChanged, true);
            app.WavelengthSliderLabel = uilabel(app.UIFigure);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.UIFigure);
            app.WavelengthSlider.Limits = [0.1 10];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.WavelengthSlider.Value = 0.1;
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.Limits = [-5 5];
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.ShiftSliderLabel = uilabel(app.UIFigure);
            app.ShiftSliderLabel.Text = 'Shift';
            app.ShiftSlider = uislider(app.UIFigure);
            app.ShiftSlider.Limits = [-6.28318530717959 6.28318530717959];
            app.ShiftSlider.ValueChangedFcn = createCallbackFcn(app, @ShiftSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appDesigner_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end